classdef neuralNetwork < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ChooseaFileLabel    matlab.ui.control.Label
        BrowseButton        matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        CharacterextractionformimageusingCNNLabel  matlab.ui.control.Label
        TextArea            matlab.ui.control.TextArea
        ExtractedTextLabel  matlab.ui.control.Label
        StartButton         matlab.ui.control.Button
    end


    properties (Access = private)
        Net; % Description
        im;
        flag=0;
        noofregions;
        regions;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load myNet;
            app.Net=myNet;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [FileName,PathName]=uigetfile({'*.*'});
            
            if FileName~=0 
            app.im=imread(fullfile(PathName,FileName));
            [~,~,dimension]=size(app.im);
            if dimension==3
            app.im=rgb2gray(app.im);
            end
            app.flag=1;
            imshow(app.im,'Parent',app.UIAxes);
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.flag==1
                app.im=nick(app.im);
                app.im=uint8(app.im);
                app.im(app.im==1)=255;
                
                
                [app.regions,app.noofregions]=extractregion0(app.im);
                for i=1:app.noofregions
                    tmp=imresize(app.im(app.regions(i,1):app.regions(i,3),app.regions(i,2):app.regions(i,4)),[20 20]);
                    tmp(tmp<255)=0;
                    x(i)=char(app.Net.classify(tmp));
                end
                app.TextArea.Value=x;
                app.TextArea.Visible='on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ChooseaFileLabel = uilabel(app.UIFigure);
            app.ChooseaFileLabel.Text = 'Choose a File';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.CharacterextractionformimageusingCNNLabel = uilabel(app.UIFigure);
            app.CharacterextractionformimageusingCNNLabel.Text = 'Character extraction form image using CNN';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.ExtractedTextLabel = uilabel(app.UIFigure);
            app.ExtractedTextLabel.Text = 'Extracted Text';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
        end
    end

    methods (Access = public)

        % Construct app
        function app = neuralNetwork

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end